classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TICTACTOEPanel   matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        ExitGameButton   matlab.ui.control.Button
        StartGameButton  matlab.ui.control.Button
        SnapShotButton   matlab.ui.control.Button
        Label            matlab.ui.control.Label
    end

    
    properties (Access = public)
        move % 1 for Player1, 2 for Player 2, either or can be the computer
        matrix % for matrix in order to check for who wins
    end
    
    methods (Access = public)
        function calc(app,m)
            %Horizontal
            if (m(1,1) == m(1,2)) && (m(1,1) == m(1,3)) && (m(1,1) ~= -1)
                result = m(1,1);
            elseif (m(2,1) == m(2,2)) && (m(2,1) == m(2,3)) && (m(2,1) ~= -1)
                result = m(2,1);
            elseif (m(3,1) == m(3,2)) && (m(3,1) == m(3,3)) && (m(3,1) ~= -1)
                result = m(3,3);
                
            %Vertical
            elseif (m(1,1) == m(2,1)) && (m(2,1) == m(3,1)) && (m(3,1) ~= -1)
                result = m(1,1);
            elseif (m(1,2) == m(2,2)) && (m(2,2) == m(3,2)) && (m(2,2) ~= -1)
                result = m(2,2);
            elseif (m(1,3) == m(2,3)) && (m(2,3) == m(3,3)) && (m(3,3) ~= -1)
                result = m(3,3);
            %Diagonal
            elseif (m(1,1) == m(2,2)) && (m(2,2) == m(3,3)) && (m(2,2) ~= -1)
                result = m(1,1);
            elseif (m(1,3) == m(2,2)) && (m(2,2) == m(3,1)) && (m(2,2) ~= -1)
                result = m(2,2);
            elseif ~ismember(m,-1)
                result = 0;
            else
                result = -1;
            end
            
            if result == 1
                app.Label.Text = 'Player has Won!';
                startupFcn(app)
            elseif result == 2
                app.Label.Text = 'Computer has Won!';
            elseif result == 0
                app.Label.Text = 'Tie Game!';
            end
        end
    end


    methods (Access = private)
        
        function startupFcn(app)
            app.Button.Enable = 'OFF';
            app.Button_2.Enable = 'OFF';
            app.Button_3.Enable = 'OFF';
            app.Button_4.Enable = 'OFF';
            app.Button_5.Enable = 'OFF';
            app.Button_6.Enable = 'OFF';
            app.Button_7.Enable = 'OFF';
            app.Button_8.Enable = 'OFF';
            app.Button_9.Enable = 'OFF';
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            prompt = {'Who goes first? insert 1 for you and 2 for the computer:'};
            dlgtitle = 'Player Selection';
            fieldsize = (1);
            definput = {'1'};
            player = inputdlg(prompt,dlgtitle,fieldsize,definput);
            app.Button.Enable = 'ON';
            app.Button_2.Enable = 'ON';
            app.Button_3.Enable = 'ON';
            app.Button_4.Enable = 'ON';
            app.Button_5.Enable = 'ON';
            app.Button_6.Enable = 'ON';
            app.Button_7.Enable = 'ON';
            app.Button_8.Enable = 'ON';
            app.Button_9.Enable = 'ON';
            app.Button.Text = '-';
            app.Button_2.Text = '-';
            app.Button_3.Text = '-';
            app.Button_4.Text = '-';
            app.Button_5.Text = '-';
            app.Button_6.Text = '-';
            app.Button_7.Text = '-';
            app.Button_8.Text = '-';
            app.Button_9.Text = '-';
            app.move = str2num(player{1});
            app.matrix = -1 * ones(3,3);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button.Text = 'O';
                app.matrix(1,1) = 1;
             else %player 2 need to add in computer moves
                app.move = 1;
                app.Button.Text = 'X';
                app.matrix(1,1) = 2;
             end
             app.Button.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_2.Text = 'O';
                app.matrix(1,2) = 1;
             else %player 2 need to add in computer moves.
                app.move = 1;
                app.Button_2.Text = 'X';
                app.matrix(1,2) = 2;
             end
             app.Button_2.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_3.Text = 'O';
                app.matrix(1,3) = 1;
             else %player 2 need to add in computer moves.
                app.move = 1;
                app.Button_3.Text = 'X';
                app.matrix(1,3) = 2;
             end
             app.Button_3.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_4.Text = 'O';
                app.matrix(2,1) = 1;
             else %player 2 need to add in the moves from the computer
                app.move = 1;
                app.Button_4.Text = 'X';
                app.matrix(2,1) = 2;
             end
             app.Button_4.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_5.Text = 'O';
                app.matrix(2,2) = 1;
             else %player 2 need to add in computer moves
                app.move = 1;
                app.Button_5.Text = 'X';
                app.matrix(2,2) = 2;
             end
             app.Button_5.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_6.Text = 'O';
                app.matrix(2,3) = 1;
             else %player 2 need to add in computer moves
                app.move = 1;
                app.Button_6.Text = 'X';
                app.matrix(2,3) = 2;
             end
             app.Button_6.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_7.Text = 'O';
                app.matrix(3,1) = 1;
             else %player 2 need to add in computer moves
                app.move = 1;
                app.Button_7.Text = 'X';
                app.matrix(3,1) = 2;
             end
             app.Button_7.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_8.Text = 'O';
                app.matrix(3,2) = 1;
             else %player 2 need to add in computer moves
                app.move = 1;
                app.Button_8.Text = 'X';
                app.matrix(3,2) = 2;
             end
             app.Button_8.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
             if app.move == 1 %player 1
                app.move = 2;
                app.Button_9.Text = 'O';
                app.matrix(3,3) = 1;
             else %player 2 need to add in computer moves
                app.move = 1;
                app.Button_9.Text = 'X';
                app.matrix(3,3) = 2;
             end
             app.Button_9.Enable = 'OFF';
             calc(app,app.matrix);
        end

        % Button pushed function: ExitGameButton
        function ExitGameButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SnapShotButton
        function SnapShotButtonPushed(app, event)
        %Load the code
        data = importdata('impross.m');
        cam = webcam(1);
        preview(cam);
        picture = snapshot(cam);
        % this will display the frame in a figure window.
        image(picture);
        % move the function definition to the end of the code
        result = impross(picture, picture);
        disp(result);
        %fprintf(' %i %i %i',shapes, row, col);
        clear cam;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TICTACTOEPanel = uipanel(app.UIFigure);
            app.TICTACTOEPanel.Title = 'TIC TAC TOE';
            app.GridLayout = uigridlayout(app.TICTACTOEPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.ExitGameButton = uibutton(app.Panel, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGameButtonPushed, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.StartGameButton = uibutton(app.Panel, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.SnapShotButton = uibutton(app.Panel, 'push');
            app.SnapShotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotButtonPushed, true);
            app.SnapShotButton.Text = 'Snap Shot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end