classdef motor_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RunSwitch                matlab.ui.control.RockerSwitch
        RunSwitchLabel           matlab.ui.control.Label
        directionSwitch          matlab.ui.control.ToggleSwitch
        directionSwitchLabel     matlab.ui.control.Label
        DutycycleEditField       matlab.ui.control.NumericEditField
        DutycycleEditFieldLabel  matlab.ui.control.Label
        startButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        % some information
        % frequency = 16KHz 
        % time period = 62.5uS = 0.0000625 S
        % PWM pin = D2
        % IN1 pin = D5
        % IN2 pin = D6
        mega % object for the arduino
        time_period 
        on_time % time when the PWM is on
        off_time % time when the PWM is off
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            % does the inital set up here because app designer sucks
            app.mega = arduino('COM3', 'Mega2560');
            app.time_period = 0.0000625;
            app.on_time = app.time_period * (app.DutycycleEditField / 100); 
            app.off_time = app.time_period * ((100 - app.DutycycleEditField) / 100);
            % sets the inital direction of the motor
            % main loop of the app
            while 1
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = {'start'; ''};
            app.DutycycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutycycleEditFieldLabel.Text = 'Duty cycle%';
            app.DutycycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutycycleEditField.Value = 50;
            app.directionSwitchLabel = uilabel(app.UIFigure);
            app.directionSwitchLabel.Text = 'direction';
            app.directionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.directionSwitch.Items = {'CW', 'CCW'};
            app.directionSwitch.Value = 'CW';
            app.RunSwitchLabel = uilabel(app.UIFigure);
            app.RunSwitchLabel.Text = {'Run'; ''};
            app.RunSwitch = uiswitch(app.UIFigure, 'rocker');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end