classdef project3app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        topositionButton     matlab.ui.control.Button
        ColorEditField       matlab.ui.control.EditField
        ColorEditFieldLabel  matlab.ui.control.Label
        shapeEditField       matlab.ui.control.EditField
        shapeEditFieldLabel  matlab.ui.control.Label
        getforegroundButton  matlab.ui.control.Button
        getbackgroundButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        background % background image
        foreground % foreground image
        cam % camera object
        pointer % motor object
        mega % arduino object
        theta % current angle that the pointer is at
        image % the impross object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initializes all the needed objects
            app.mega = arduino('COM3','MEGA2560');
            app.cam = webcam('name'); % will have to fill in  with name later
            app.pointer = motor(app.mega, "D2", "D3", "D5", 0.0000625, 50 ,...
                "D11", "D12");
            app.theta = 0;
            app.background = -1;
            app.foreground = -1;
         
        end

        % Button pushed function: getbackgroundButton
        function getbackgroundButtonPushed(app, event)
            % uses the camera to take a background image snap shot
            app.background = snapshot(app.cam);
        end

        % Button pushed function: getforegroundButton
        function getforegroundButtonPushed(app, event)
            % uses the camera to take a foreground image and makes the
            % impross object
            app.foreground = snapshot(app.cam);
            app.image = impross(app.background, app.foreground);

        end

        % Button pushed function: topositionButton
        function topositionButtonPushed(app, event)
            % moves the pointer to point to the shape that matchs the shape
            % and color of the text boxes

            % checks if there is an object that matches the inputs of the
            % text feild
            [row, col] = app.image.imgetcentriod(app.ColorEditField, app.shapeEditField);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.getbackgroundButton = uibutton(app.UIFigure, 'push');
            app.getbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @getbackgroundButtonPushed, true);
            app.getbackgroundButton.Text = 'get background';
            app.getforegroundButton = uibutton(app.UIFigure, 'push');
            app.getforegroundButton.ButtonPushedFcn = createCallbackFcn(app, @getforegroundButtonPushed, true);
            app.getforegroundButton.Text = 'get foreground';
            app.shapeEditFieldLabel = uilabel(app.UIFigure);
            app.shapeEditFieldLabel.Text = 'shape';
            app.shapeEditField = uieditfield(app.UIFigure, 'text');
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.UIFigure, 'text');
            app.topositionButton = uibutton(app.UIFigure, 'push');
            app.topositionButton.ButtonPushedFcn = createCallbackFcn(app, @topositionButtonPushed, true);
            app.topositionButton.Text = 'to position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project3app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end